classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUI_BubbleViewer    matlab.ui.Figure
        FileMenu            matlab.ui.container.Menu
        NewMenu             matlab.ui.container.Menu
        OpenfileMenu        matlab.ui.container.Menu
        ChoosefolderMenu    matlab.ui.container.Menu
        ExportMenu          matlab.ui.container.Menu
        matformatMenu       matlab.ui.container.Menu
        csvformatMenu       matlab.ui.container.Menu
        CloseMenu           matlab.ui.container.Menu
        EditMenu            matlab.ui.container.Menu
        UndoMenu            matlab.ui.container.Menu
        RedoMenu            matlab.ui.container.Menu
        CutMenu             matlab.ui.container.Menu
        CopyMenu            matlab.ui.container.Menu
        PasteMenu           matlab.ui.container.Menu
        ViewMenu            matlab.ui.container.Menu
        PreviousMenu        matlab.ui.container.Menu
        NextMenu            matlab.ui.container.Menu
        WindowMenu          matlab.ui.container.Menu
        MinimiseMenu        matlab.ui.container.Menu
        FullscreenMenu      matlab.ui.container.Menu
        ZoomMenu            matlab.ui.container.Menu
        HelpMenu            matlab.ui.container.Menu
        LicenseMenu         matlab.ui.container.Menu
        DocumentationMenu   matlab.ui.container.Menu
        SelectfolderButton  matlab.ui.control.StateButton
        STEP3Tab            matlab.ui.container.Tab
        STEP4Tab            matlab.ui.container.Tab
        TabGroup            matlab.ui.container.TabGroup
        STEP1Tab            matlab.ui.container.Tab
        STEP2Tab            matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SelectfolderButton
        function SelectfolderButtonValueChanged(app, event)
            %value = app.SelectfolderButton.Value;
            path=uigetdir;                    % Calls 'uigetdir' to obtain the directory location from the user
            app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on';      
            app.DirectoryLocationLabel.Text=path;  % Sets the label text to be the selected path

            a=dir(path);                         % Obtains the contents of the selected path.
            b={a(:).name}';                      % Gets the name of the files/folders of the contents and stores them appropriately in a cell array
            b(ismember(b,{'.','..'})) = [];      % Removes unnecessary '.' and '..' results from the display       
            app.UITable.Data=b;                  % Displays the directory information to the UITable.

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUI_BubbleViewer = uifigure('Visible', 'off');
            app.GUI_BubbleViewer.Name = 'UI Figure';
            app.FileMenu = uimenu(app.GUI_BubbleViewer);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Text = 'New';
            app.OpenfileMenu = uimenu(app.FileMenu);
            app.OpenfileMenu.Text = 'Open file';
            app.ChoosefolderMenu = uimenu(app.OpenfileMenu);
            app.ChoosefolderMenu.Text = 'Choose folder';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Text = 'Export';
            app.matformatMenu = uimenu(app.ExportMenu);
            app.matformatMenu.Text = '.mat format';
            app.csvformatMenu = uimenu(app.ExportMenu);
            app.csvformatMenu.Text = '.csv format';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.Text = 'Close';
            app.EditMenu = uimenu(app.GUI_BubbleViewer);
            app.EditMenu.Text = 'Edit';
            app.UndoMenu = uimenu(app.EditMenu);
            app.UndoMenu.Text = 'Undo';
            app.RedoMenu = uimenu(app.EditMenu);
            app.RedoMenu.Text = 'Redo';
            app.CutMenu = uimenu(app.EditMenu);
            app.CutMenu.Text = 'Cut';
            app.CopyMenu = uimenu(app.EditMenu);
            app.CopyMenu.Text = 'Copy';
            app.PasteMenu = uimenu(app.EditMenu);
            app.PasteMenu.Text = 'Paste';
            app.ViewMenu = uimenu(app.GUI_BubbleViewer);
            app.ViewMenu.Text = 'View';
            app.PreviousMenu = uimenu(app.ViewMenu);
            app.PreviousMenu.Text = 'Previous';
            app.NextMenu = uimenu(app.ViewMenu);
            app.NextMenu.Text = 'Next';
            app.WindowMenu = uimenu(app.GUI_BubbleViewer);
            app.WindowMenu.Text = 'Window';
            app.MinimiseMenu = uimenu(app.WindowMenu);
            app.MinimiseMenu.Text = 'Minimise';
            app.FullscreenMenu = uimenu(app.WindowMenu);
            app.FullscreenMenu.Text = 'Full screen';
            app.ZoomMenu = uimenu(app.WindowMenu);
            app.ZoomMenu.Text = 'Zoom';
            app.HelpMenu = uimenu(app.GUI_BubbleViewer);
            app.HelpMenu.Text = 'Help';
            app.LicenseMenu = uimenu(app.HelpMenu);
            app.LicenseMenu.Text = 'License';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.Text = 'Documentation';
            app.TabGroup = uitabgroup(app.GUI_BubbleViewer);
            app.STEP1Tab = uitab(app.TabGroup);
            app.STEP1Tab.Title = 'STEP1';
            app.SelectfolderButton = uibutton(app.STEP1Tab, 'state');
            app.SelectfolderButton.ValueChangedFcn = createCallbackFcn(app, @SelectfolderButtonValueChanged, true);
            app.SelectfolderButton.Text = 'Select folder';
            app.STEP2Tab = uitab(app.TabGroup);
            app.STEP2Tab.Title = 'STEP 2';
            app.STEP3Tab = uitab(app.TabGroup);
            app.STEP3Tab.Title = 'STEP 3';
            app.STEP4Tab = uitab(app.TabGroup);
            app.STEP4Tab.Title = 'STEP 4';
            % Show the figure after all components are created
            app.GUI_BubbleViewer.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUI_BubbleViewer)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUI_BubbleViewer)
        end
    end
end